classdef figrApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FIGRParameterExplorerUIFigure  matlab.ui.Figure
        xntgAxes                       matlab.ui.control.UIAxes
        pSmoothingLabel                matlab.ui.control.Label
        pSmoothingSlider               matlab.ui.control.Slider
        GeneListBoxLabel               matlab.ui.control.Label
        GeneListBox                    matlab.ui.control.ListBox
        NucleusListBoxLabel            matlab.ui.control.Label
        NucleusListBox                 matlab.ui.control.ListBox
        xThresholdSliderLabel          matlab.ui.control.Label
        xThresholdSlider               matlab.ui.control.Slider
        SaveparametersButton           matlab.ui.control.Button
        LoadparametersButton           matlab.ui.control.Button
        tSafetySliderLabel             matlab.ui.control.Label
        tSafetySlider                  matlab.ui.control.Slider
        vThresholdQuestionButton       matlab.ui.control.Button
        xThresholdQuestionButton       matlab.ui.control.Button
        vThresholdSliderLabel          matlab.ui.control.Label
        vThresholdSlider               matlab.ui.control.Slider
        pSmoothingQuestionButton       matlab.ui.control.Button
        tSafetyQuestionButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        xntg % Description
        tt
        nucleusNames
        geneNames
        pvxArr
        pvxUser
        paramFileLoaded = 0; % Flag for loaded file with p, v, and x parameters
        userChangedParam % Flag for wheather user has changed the params on the gui
        geneMap = containers.Map
        nucleusMap = containers.Map
    end
    
    methods (Access = private)
        
        function [xntg,tt,nucleusNames,geneNames] = readGeneExprFiles (app)
            
            fnameTN = "tn.txt";
            fnameXNTG = "xntg.txt";
            
            %======== READ THREE-COLUMN FILE CONTAINING SAMPLE/TIME/NUCLEUS INFO
            fid = fopen (fnameTN, 'r');        %Opens File containing time points
            matrix = textscan (fid, '%s%s%s', 'Delimiter', '\t', 'HeaderLines', 1, 'MultipleDelimsAsOne', 1);
            fclose (fid);
            ss = strtrim (matrix{1,1});      % list of sample IDs for each sample s (whitespace removed!)
            ts = str2double (matrix{1,2});   % list of times t_s for each sample s
            ns = str2double (matrix{1,3});   % list of nuclei n_s for each sample s
            tt = unique (ts);  % actual time T_t corresponding to timepoint index t
            nn = unique (ns);  % nucleus label N_n corresponding to nucleus index n
            smax = numel (ss); % number of samples
            tmax = numel (tt); % number of unique timepoints
            nmax = numel (nn); % number of unique nuclei
            
            %======== COMPARE SAMPLE NAMES BETWEEN TWO FILES
            fid = fopen(fnameXNTG , 'r');
            dummy = fgetl (fid);
            dummy = split (strtrim (dummy)); % split into strings
            ssFromMainFile = dummy(2:end);
            assert (numel(ssFromMainFile) == smax, 'ERROR: incompatible numbers of samples in data files!');
            assert (isequal (ssFromMainFile, ss), 'ERROR: differing sample names in data files!');
            %======== READ EACH LINE OF THE FILE AND PARSE IT INTO THE ARRAY
            geneNames = [];                          % could be "gg"
            x_gs = [];
            while true
                dummy = fgetl (fid);                 % read one line from the file (as char vec)
                if ~ischar(dummy); break; end
                dummy = split (strtrim (dummy));     % split line into strings
                geneNames = [geneNames ; dummy(1)];  % first item is gene name
                dummy = str2double ( dummy(2:end) )'; % remaining items are gene expression levels
                x_gs = [x_gs ; dummy];
            end
            fclose (fid);
            geneNames = string (geneNames);          % convert from CELL ARRAY OF CHAR VECTORS to ARRAY OF STRINGS
            gmax = numel (geneNames);
            
            %======== AVERAGE OVER REPLICATES
            [tnu, ia, ic] = unique ([ts ns], 'rows', 'stable');
            umax = numel (ia);  % number of unique datapoints
            x_gu = zeros ([gmax umax]);
            for g = 1:gmax
               	x_gu(g,:) = accumarray (ic, x_gs(g,:), [], @mean)';
            end
            
            %======== RESHAPE x(n,t,g) INTO x(g,u)
            xntg = zeros ([nmax tmax gmax]);
            for u = 1:umax
                tn = tnu(u,:);  % time and position corresponding to sample s
                t = tn(1);
                n = tn(2);
                indext = find (tt==t);  % time index corresponding to sample s
                indexn = find (nn==n);
                xntg (indexn,indext,:) = x_gu (:,u);
            end
            
            %======== FINALIZE RETURN-VALUES
            nucleusNames = string (nn);
        end
        
        
        %==========================================================================================
        %  DRAW THE GENE EXPRESSION TRAJECTORY FOR THE SELECTED GENE IN THE SELECTED NUCLEUS (OR CONDITION)
        %==========================================================================================
        function redrawXNTGAxes (app)
            tt = app.tt;
            
            gNames = string(app.GeneListBox.Value);                      % name of selected gene
            gNames = gNames(:);
            [commonElements,gIndices] = intersect(app.geneNames, gNames,'stable');
            nNames = string(app.NucleusListBox.Value);            % name of selected gene (made to string)
            nNames = nNames(:);                            % flatten (otherwise it's column vec)
            nIndices = find (app.nucleusNames==nNames);     % index of selected gene
            
            % plot trajs
            cla (app.xntgAxes);
            hold (app.xntgAxes, 'on');
            tpltmin = min(tt);
            tpltmax = max(tt);
            tplt = linspace (tpltmin, tpltmax, 50);
            tplt = unique ([tplt'  ; tt]);
           
            colorOrder = get (app.xntgAxes, 'colororder');
            vt = [];
            
            p = app.pSmoothingSlider.Value;                                
            vThreshold = app.vThresholdSlider.Value;
            xThreshold = app.xThresholdSlider.Value;
            tsafety = app.tSafetySlider.Value;
            
            %-----------------------------------------------
            % Visualize the thresholds
            %-----------------------------------------------
            plot (app.xntgAxes, ...
                [tpltmin tpltmax], [xThreshold xThreshold], 'k--', ...
                [tpltmin tpltmax], [xThreshold xThreshold+vThreshold*(tpltmax-tpltmin)], 'k--', ...
                [tpltmin tpltmax], [xThreshold xThreshold-vThreshold*(tpltmax-tpltmin)], 'k--');
                               
            %-----------------------------------------------
            % Loop over all selected genes and nuclei
            %-----------------------------------------------
            for k=1:length(gIndices) 
                for j=1:length(nIndices)
                    n = nIndices(j);
                    g = gIndices(k);
                    xSplineFN = csaps (tt, app.xntg(n,:,g), p);
                    xSpline = fnval (xSplineFN,tplt);
                    vSplineFN = fnder (xSplineFN);         % Calculate derivative w.r.t. gene g!
                    for t = 1:length(tt)                   % At each timepoint
                        vt(t) = fnval (vSplineFN, tt(t));  % Evaluate derivative
                        if (abs(vt(t)) > vThreshold)	 % If derivative is significant
                            yt(t) = sign (vt(t));      % Infer on/off state from sign of derivative
                        else							% Else infer on/off state from size of x itself
                            yt(t) = sign (app.xntg(n,t,g) - xThreshold);
                        end           					% YLL 2018-10-2
                    end   
                    
                    %======= Check tsafety.                      
                    tmax = length(tt);
                    ytFilt = yt;                   
                    for t=1+tsafety:tmax-tsafety
                        % FILTER OUT DATAPOINTS WHOSE SLOPES ARE
                        % NOT PART OF A RUN OF tSafety OR MORE OF THE SAME
                        for t2=t-tsafety : t+tsafety
                            if (yt(t2) ~= yt(t))
                                ytFilt(t) = 0;
                            end
                        end
                    end % for t
                    
                    
                    yt = ytFilt;
                    %======= Plot ON points as filled circles, OFF points as empty circles
                    tSel = (yt>0);
                    plot (app.xntgAxes, app.tt(tSel), app.xntg (n,tSel,g), 'o', 'MarkerFaceColor', colorOrder(j,:), 'color', colorOrder(j,:));
                    tSel = (yt<=0);
                    plot (app.xntgAxes, app.tt(tSel), app.xntg (n,tSel,g), 'o', 'color', colorOrder(j,:));
                    %======= Plot spline
                    plot (app.xntgAxes, tplt, xSpline, '-', 'color', colorOrder(j,:));
                end
            end
            app.xntgAxes.YLim = [0 1];                                           % fix this
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            %==========================================================================================
            %  READ THE DATA FILE!
            %==========================================================================================
            [app.xntg, app.tt, app.nucleusNames, app.geneNames] = readGeneExprFiles (app);
            
            %==========================================================================================
            %  POPULATE THE ListBox and DropDown WITH ITEMS FOR THE USER TO
            %  SELECT
            %==========================================================================================
            app.GeneListBox.Items = app.geneNames;
            app.NucleusListBox.Items = app.nucleusNames;
            
            app.pSmoothingSlider.MajorTicks = linspace (0, 1, 11);
            app.vThresholdSlider.MajorTicks = linspace (0, 0.02, 11);
            app.xThresholdSlider.MajorTicks = linspace (0, 1, 11);
            
            [numNuclei row] = size(app.nucleusNames);
            [numGenes row] = size(app.geneNames);

            % Store values of params p, v, and x for each gene and nucleus.
            app.pvxArr(1:numGenes, 1:numNuclei, 1) = app.pSmoothingSlider.Value;
            app.pvxArr(1:numGenes, 1:numNuclei, 2) = app.vThresholdSlider.Value;
            app.pvxArr(1:numGenes, 1:numNuclei, 3) = app.xThresholdSlider.Value;
            
            % Mapper for gene - number (in array)
            app.geneMap = containers.Map(app.geneNames, [1:numGenes]);           
            % Mapper for nucleus - number (in array)
            app.nucleusMap = containers.Map(app.nucleusNames, [1:numNuclei]);
                      
            redrawXNTGAxes (app);
        end

        % Value changed function: GeneListBox
        function GeneListBoxValueChanged(app, event)
            
            value = app.GeneListBox.Value;
            g = app.geneMap(string(value));  
            
            % Update inserted by the user parameters
            for i=1:numel(string(app.NucleusListBox.Value))
                app.pSmoothingSlider.Value = app.pvxArr(app.geneMap(string(app.GeneListBox.Value)), ...
                    app.nucleusMap(string(app.NucleusListBox.Value(i))), 1);
                 app.vThresholdSlider.Value = app.pvxArr(app.geneMap(string(app.GeneListBox.Value)), ...
                    app.nucleusMap(string(app.NucleusListBox.Value(i))), 2);
                 app.xThresholdSlider.Value = app.pvxArr(app.geneMap(string(app.GeneListBox.Value)), ...
                    app.nucleusMap(string(app.NucleusListBox.Value(i))), 3);
            end

            if(app.paramFileLoaded)                        
                for n=1:numel(string(app.NucleusListBox.Value))
                    if(app.userChangedParam(1,n,g) == 0)
                        app.pSmoothingSlider.Value = app.pvxUser(1,n,g);                                   
                    end
                    if(app.userChangedParam(2,n,g) == 0)
                        app.vThresholdSlider.Value = app.pvxUser(2,n,g);
                    end
                    if(app.userChangedParam(3,n,g) == 0)
                        app.xThresholdSlider.Value = app.pvxUser(3,n,g);
                    end
                end
            end
 
            fprintf ("APP: Selected gene %s\n", string(value));           
            redrawXNTGAxes (app);

        end

        % Value changed function: NucleusListBox
        function NucleusListBoxValueChanged(app, event)
            value = app.NucleusListBox.Value;
            fprintf ("APP: Selected nucle[i] %s\n", string(value));
            
            % Update inserted by the user parameters
            for i=1:numel(string(app.NucleusListBox.Value))
                app.pSmoothingSlider.Value = app.pvxArr(app.geneMap(string(app.GeneListBox.Value)), ...
                    app.nucleusMap(string(app.NucleusListBox.Value(i))), 1);
                 app.vThresholdSlider.Value = app.pvxArr(app.geneMap(string(app.GeneListBox.Value)), ...
                    app.nucleusMap(string(app.NucleusListBox.Value(i))), 2);
                 app.xThresholdSlider.Value = app.pvxArr(app.geneMap(string(app.GeneListBox.Value)), ...
                    app.nucleusMap(string(app.NucleusListBox.Value(i))), 3);
            end
            
            redrawXNTGAxes (app);
        end

        % Value changed function: pSmoothingSlider
        function pSmoothingSliderValueChanged(app, event)
            value = app.pSmoothingSlider.Value;
            % Store changed p value to pvxArr array.
            for i=1:numel(string(app.NucleusListBox.Value))
                app.pvxArr(app.geneMap(string(app.GeneListBox.Value)), ...
                    app.nucleusMap(string(app.NucleusListBox.Value(i))), 1) = value;
            end
            % Set flag to 1 for changed param after file with parameter was loaded.
            if(app.paramFileLoaded)                          
                g = app.geneMap(string(app.GeneListBox.Value));             
                for i=1:numel(string(app.NucleusListBox.Value))
                    n = app.nucleusMap(string(app.NucleusListBox.Value(i)));
                    app.userChangedParam(1,n,g) = 1;
                end              
            end
            redrawXNTGAxes (app);
        end

        % Value changed function: vThresholdSlider
        function vThresholdSliderValueChanged(app, event)
            value = app.vThresholdSlider.Value;
            % Store changed v value to pvxArr array.
            for i=1:numel(string(app.NucleusListBox.Value))
                app.pvxArr(app.geneMap(string(app.GeneListBox.Value)), ...
                    app.nucleusMap(string(app.NucleusListBox.Value(i))), 2) = value;
            end
            % Set flag to 1 for changed param after file with parameter was loaded.
            if(app.paramFileLoaded)                          
                g = app.geneMap(string(app.GeneListBox.Value));             
                for i=1:numel(string(app.NucleusListBox.Value))
                    n = app.nucleusMap(string(app.NucleusListBox.Value(i)));
                    app.userChangedParam(2,n,g) = 1;
                end              
            end
            redrawXNTGAxes (app);
        end

        % Value changed function: xThresholdSlider
        function xThresholdSliderValueChanged(app, event)
            value = app.xThresholdSlider.Value;
            % Store changed x value to pvxArr array.
            for i=1:numel(string(app.NucleusListBox.Value))
                app.pvxArr(app.geneMap(string(app.GeneListBox.Value)), app.nucleusMap(string(app.NucleusListBox.Value(i))), 3) = value;
            end
            
            % Set flag to 1 for changed param after file with parameter was loaded.
            if(app.paramFileLoaded)                          
                g = app.geneMap(string(app.GeneListBox.Value));             
                for i=1:numel(string(app.NucleusListBox.Value))
                    n = app.nucleusMap(string(app.NucleusListBox.Value(i)));
                    app.userChangedParam(3,n,g) = 1;
                end              
            end
            
            redrawXNTGAxes (app);
        end

        % Button pushed function: SaveparametersButton
        function SaveparametersButtonPushed(app, event)
            
            [file, path] = uiputfile('*'); %open file
            
            filename = strcat(path,file);
            fmtstr = '%.4f';
            
            [numNuclei row] = size(app.nucleusNames);
            [numGenes row] = size(app.geneNames);
            
            fid = fopen (filename, 'w');

           fprintf (fid, '3 dims\n'); % 3D 
           fprintf (fid, '3 elems\n'); % 3 elements p, v, and a.
           fprintf (fid, '%d', numNuclei);
           fprintf (fid, ' elems\n');          
           fprintf (fid, '%d', numGenes);
           fprintf (fid, ' elems\n');
           
           for i=1:numGenes
                for j=1:numNuclei

                    %fprintf('Gene: %s Nuclei: %s| p=%f v=%f x=%f \n',string(geneName), ...
                    %string(nucleusName), app.pvxArr(i,j,1), app.pvxArr(i,j,2));
                
                    fprintf (fid, fmtstr, string(app.pvxArr(i,j,1)));
                    fprintf (fid, '\t');
                    fprintf (fid, fmtstr, string(app.pvxArr(i,j,2)));
                    fprintf (fid, '\t');
                    fprintf (fid, fmtstr, string(app.pvxArr(i,j,3)));
                    fprintf (fid, '\n');
                end
                
                fprintf (fid, '\n');
                
            end
            
            fclose (fid);
                     
        end

        % Button pushed function: LoadparametersButton
        function LoadparametersButtonPushed(app, event)
            
            [file, path] = uigetfile('*'); % Open file.
            filename = strcat(path,file);
            
            % Read input file with p, v, and x paramaters.
            fid = fopen (filename, 'r');
            dmax = fscanf (fid, '%d');   % Read number of dimensions.
            fgets (fid);                 % Skip to next line.
            for d=1:dmax
                cmaxd(d) = fscanf (fid, '%d');  % Read number of elements.
                fgets (fid);                    % Skip to next line.
            end
            app.pvxUser = fscanf (fid, '%f');      % Read in all elements.
            app.pvxUser = reshape (app.pvxUser, cmaxd);
            fclose (fid);
            
            app.paramFileLoaded = 1;
            
            % Initialize array for checking whether a user had changed 
            % the parameters p, v, and x after loading the params file.
            
            [numNuclei row] = size(app.nucleusNames);
            [numGenes row] = size(app.geneNames);           
            for n=1:numNuclei
                for g=1:numGenes
                    app.userChangedParam(1,n,g) = 0;
                    app.userChangedParam(2,n,g) = 0;
                    app.userChangedParam(3,n,g) = 0;
                end
            end
             
            for n=1:numNuclei
                for g=1:numGenes
                    app.pvxArr(g,n,1) = app.pvxUser(1,n,g);
                    app.pvxArr(g,n,2) = app.pvxUser(2,n,g);
                    app.pvxArr(g,n,3) = app.pvxUser(3,n,g);
                    % Update current slider values.
                    app.pSmoothingSlider.Value = app.pvxUser(1,n,g);
                    app.vThresholdSlider.Value = app.pvxUser(2,n,g);
                    app.xThresholdSlider.Value = app.pvxUser(3,n,g);
                end
            end
            
            redrawXNTGAxes (app);
            
        end

        % Value changed function: tSafetySlider
        function tSafetySliderValueChanged(app, event)
            value = app.tSafetySlider.Value;
            % Force cursor to land on discrete values.
            % Determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % Move the slider to that option.
            event.Source.Value = event.Source.MajorTicks(minIdx);            
            value = event.Source.MajorTicks(minIdx);
            redrawXNTGAxes (app);
        end

        % Button pushed function: vThresholdQuestionButton
        function vThresholdQuestionButtonPushed(app, event)
            msgbox({'vThreshold controls the derivative threshold for the ON/OFF state of the gene at time t.'});      
        end

        % Button pushed function: xThresholdQuestionButton
        function xThresholdQuestionButtonPushed(app, event)
            msgbox({'xThreshold controls the expression threshold for the ON/OFF state of the gene if its derivative at time t is greater than vThreshold.'});
        end

        % Button pushed function: pSmoothingQuestionButton
        function pSmoothingQuestionButtonPushed(app, event)
            msgbox({'pSmoothing controls the smoothing parameter of the cubic spline.' });
        end

        % Button pushed function: tSafetyQuestionButton
        function tSafetyQuestionButtonPushed(app, event)
            msgbox({'tSafety param filters out data points when estimating params R and lambda. Filtered data points have slopes are not part of a run of its value. This param is not stored in output but provides '});
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FIGRParameterExplorerUIFigure = uifigure;
            app.FIGRParameterExplorerUIFigure.Name = 'FIGR Parameter Explorer';
            app.xntgAxes = uiaxes(app.FIGRParameterExplorerUIFigure);
            title(app.xntgAxes, 'Gene expression trajectory')
            xlabel(app.xntgAxes, 'Time t')
            ylabel(app.xntgAxes, 'Expression x')
            app.pSmoothingLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.pSmoothingLabel.Text = 'pSmoothing';
            app.pSmoothingSlider = uislider(app.FIGRParameterExplorerUIFigure);
            app.pSmoothingSlider.Limits = [0 1];
            app.pSmoothingSlider.ValueChangedFcn = createCallbackFcn(app, @pSmoothingSliderValueChanged, true);
            app.pSmoothingSlider.Value = 0.7;
            app.GeneListBoxLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.GeneListBoxLabel.Text = 'Gene';
            app.GeneListBox = uilistbox(app.FIGRParameterExplorerUIFigure);
            app.GeneListBox.Multiselect = 'on';
            app.GeneListBox.ValueChangedFcn = createCallbackFcn(app, @GeneListBoxValueChanged, true);
            app.GeneListBox.Value = {'Item 1'};
            app.NucleusListBoxLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.NucleusListBoxLabel.Text = 'Nucleus';
            app.NucleusListBox = uilistbox(app.FIGRParameterExplorerUIFigure);
            app.NucleusListBox.Items = {'8226', '8227'};
            app.NucleusListBox.Multiselect = 'on';
            app.NucleusListBox.ValueChangedFcn = createCallbackFcn(app, @NucleusListBoxValueChanged, true);
            app.NucleusListBox.Value = {'8226', '8227'};
            app.xThresholdSliderLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.xThresholdSliderLabel.Text = 'xThreshold';
            app.xThresholdSlider = uislider(app.FIGRParameterExplorerUIFigure);
            app.xThresholdSlider.Limits = [0 1];
            app.xThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @xThresholdSliderValueChanged, true);
            app.xThresholdSlider.Value = 0.5;
            app.SaveparametersButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.SaveparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveparametersButtonPushed, true);
            app.SaveparametersButton.Text = 'Save parameters';
            app.LoadparametersButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.LoadparametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadparametersButtonPushed, true);
            app.LoadparametersButton.Text = 'Load parameters';
            app.tSafetySliderLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.tSafetySliderLabel.Text = 'tSafety';
            app.tSafetySlider = uislider(app.FIGRParameterExplorerUIFigure);
            app.tSafetySlider.Limits = [0 10];
            app.tSafetySlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.tSafetySlider.ValueChangedFcn = createCallbackFcn(app, @tSafetySliderValueChanged, true);
            app.tSafetySlider.MinorTicks = [];
            app.vThresholdQuestionButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.vThresholdQuestionButton.ButtonPushedFcn = createCallbackFcn(app, @vThresholdQuestionButtonPushed, true);
            app.vThresholdQuestionButton.Text = '?';
            app.xThresholdQuestionButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.xThresholdQuestionButton.ButtonPushedFcn = createCallbackFcn(app, @xThresholdQuestionButtonPushed, true);
            app.xThresholdQuestionButton.Text = '?';
            app.pSmoothingQuestionButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.pSmoothingQuestionButton.ButtonPushedFcn = createCallbackFcn(app, @pSmoothingQuestionButtonPushed, true);
            app.pSmoothingQuestionButton.Text = '?';
            app.tSafetyQuestionButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.tSafetyQuestionButton.ButtonPushedFcn = createCallbackFcn(app, @tSafetyQuestionButtonPushed, true);
            app.tSafetyQuestionButton.Text = '?';
            app.vThresholdSliderLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.vThresholdSliderLabel.Text = 'vThreshold';
            app.vThresholdSlider = uislider(app.FIGRParameterExplorerUIFigure);
            app.vThresholdSlider.Limits = [0 0.02];
            app.vThresholdSlider.MajorTicks = [0 0.001111 0.002222 0.003333 0.004444 0.005555 0.006666 0.007777 0.008888 0.009999 0.01111 0.012221 0.013332 0.014443 0.015554 0.016665 0.017776 0.018887 0.02];
            app.vThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @vThresholdSliderValueChanged, true);
            app.vThresholdSlider.Value = 0.01;
        end
    end

    methods (Access = public)

        % Construct app
        function app = figrApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FIGRParameterExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FIGRParameterExplorerUIFigure)
        end
    end
end